classdef HDPB_first < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HDPB_calculationUIFigure        matlab.ui.Figure
        profile                         matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        profile_full_circle_dialog      matlab.ui.container.Menu
        profile_tangency_circle_dialog  matlab.ui.container.Menu
        profile_cross_circle_dialog     matlab.ui.container.Menu
        profile_other_dialog            matlab.ui.container.Menu
        heat                            matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        heat_insulation                 matlab.ui.container.Menu
        standard_heat                   matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        pic_2                           matlab.ui.control.Button
        pic_1                           matlab.ui.control.Button
        Tab_2                           matlab.ui.container.Tab
        result_plot                     matlab.ui.control.UIAxes
        input_information               matlab.ui.control.TextArea
        input_information_2             matlab.ui.control.TextArea
        output_information              matlab.ui.control.TextArea
        output_information_2            matlab.ui.control.TextArea
        structure                       matlab.ui.container.Panel
        mmEditFieldLabel                matlab.ui.control.Label
        bearing_diameter                matlab.ui.control.NumericEditField
        mmLabel                         matlab.ui.control.Label
        bearing_Length                  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        bearing_gap                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        bearing_bearing_num             matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        bearing_Length_single           matlab.ui.control.NumericEditField
        work_condtion                   matlab.ui.container.Panel
        FxNLabel                        matlab.ui.control.Label
        bearing_Fx                      matlab.ui.control.NumericEditField
        FyNLabel                        matlab.ui.control.Label
        bearing_Fy                      matlab.ui.control.NumericEditField
        FzNLabel                        matlab.ui.control.Label
        bearing_Fz                      matlab.ui.control.NumericEditField
        rpmLabel                        matlab.ui.control.Label
        bearing_speed                   matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        bearing_oil_type                matlab.ui.control.DropDown
        mmLabel_2                       matlab.ui.control.Label
        bearing_pitch_radius            matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        bearing_calculation_type        matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        Label_7                         matlab.ui.control.Label
        plot_type                       matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        bearing_angle_area              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
        global_para=[];
        U=[];
        L=[];
        % 各个参数的初始化
        
        %% 润滑油黏度和密度对话框
        oil_property % Description
        Vis=0.2768;
        Den=860;
        
        %% 轴承偏心率、倾斜角
        epsilion=0.8;
        titling1=0.001;
        titling2=90;


        %% 根据标准计算温度对话框
        heat_mode=0;
        heat_type=0;
        hole_type=0;
        hole_diameter=0;
        T_envir=40;
        inlet_temp=0;
        inlet_pressure=0;
        inlet_flow=0;
        hG=0;
        
        %% 修形
        Rc=0;
        small_l=0;
        profile_mode=0;
        profile_other={'1','1'};
    end
    
    methods (Access = public)
        
        function update_oil_property(app,vis,den)
            app.Vis=vis;
            app.Den=den;
        end
        
%         function update_oil_heat_property(app,specific_heat,T_envir,heat_mode)
%             app.specific_heat=specific_heat;
%             app.T_envir=T_envir;
%             app.heat_mode=heat_mode;
%         end
        
        function update_standard_heat(app,heat_mode,heat_type,hole_type,hole_diameter,hG,T_envir,inlet_temp,inlet_pressure,flow)
            %% 计算类型
            switch heat_type
                case '输入进油压力'
                    heat_type=1;
                case '输入进油量'
                    heat_type=2;
            end
            %% 进油方式
            switch hole_type
                case '单孔进油'
                    hole_position=1;
                case '周向油槽'
                    hole_position=3;
            end
            app.hole_type=hole_position;
            %% 进油孔直径
            app.hole_diameter=hole_diameter;
            %% 
            app.T_envir=T_envir;
            app.inlet_temp=inlet_temp;
            app.inlet_pressure=inlet_pressure;
            app.heat_mode=heat_mode;
            app.heat_type=heat_type;
            app.inlet_flow=flow;
            app.hG=hG;
        end
        
        function update_initial_work_para(app,epsilion, titling1, titling2)
            app.epsilion=epsilion;
            app.titling1=titling1;
            app.titling2=titling2;
        end
        
        %% 修形
        % 全凸圆弧修形
        function update_profile_full_circle(app,Rc,profile_mode)
            app.Rc=Rc;
            app.profile_mode=profile_mode;
        end
        % 相切圆弧修形
        function update_profile_tangency_circle(app,Rc,small_l,profile_mode)
            app.Rc=Rc;
            app.small_l=small_l;
            app.profile_mode=profile_mode;
        end
        %% 相交圆弧修形
        function update_profile_cross_circle(app,Rc,small_l,profile_mode)
            app.Rc=Rc;
            app.small_l=small_l;
            app.profile_mode=profile_mode;
        end
        %% 其他修形方式（用点表示）
        function update_profile_other(app,profile_other)
            app.profile_other=profile_other;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             imshow('hdpb_pic.jpg','parent',app.UIAxes_2);
%             imshow('planet_load_1.png','parent',app.UIAxes)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%             d = uiprogressdlg(app.HDPB_calculationUIFigure,'Title','计算窗口',...
%                 'Message','计算中，请稍候','Cancelable','on');
            %% 结构参数
            Rbearing=app.bearing_diameter.Value/2;
            Length=app.bearing_Length.Value;
            relative_gap_ratio=app.bearing_gap.Value;
            
            bearing_num=app.bearing_bearing_num.Value;
            bearing_single_Length=app.bearing_Length_single.Value;
            
            global_para.structure_para.Rbearing=Rbearing;
            global_para.structure_para.Length=Length;
            global_para.structure_para.CR=relative_gap_ratio;
            
            global_para.split_para.bearing_num=bearing_num;
            global_para.split_para.bearing_single_Length=bearing_single_Length;
            
            %% 工况参数
            Fx=app.bearing_Fx.Value;
            if Fx~=0
                app.titling1=0.001;
                app.titling2=90;
            else
                app.titling1=0;
                app.titling2=0;
            end
            Fy=app.bearing_Fy.Value;
            Fz=app.bearing_Fz.Value;
            RS=app.bearing_speed.Value;
            bearing_pitch=app.bearing_pitch_radius.Value;
            
            bearing_angle=app.bearing_angle_area.Value;
            %% ISO VG 320 / 自己输入
            oil_name=app.bearing_oil_type.Value;
            switch oil_name
                case 'ISO VG 320'
                    global_para.work_condition.bearing_oil_type=1;
                case '其他'
                    global_para.work_condition.bearing_oil_type=2;
                case 'ISO VG 100'
                    global_para.work_condition.bearing_oil_type=3;
            end
            Vis=app.Vis;
            Den=app.Den;
            
            global_para.work_condition.Fx=Fx;
            global_para.work_condition.Fy=Fy;
            global_para.work_condition.Fz=Fz;
            global_para.work_condition.RS=RS;
            global_para.work_condition.Vis=Vis;
            global_para.work_condition.DEN0=Den;
            global_para.work_condition.bearing_pitch_radius=bearing_pitch;
            global_para.work_condition.bearing_angle=bearing_angle*2*pi/360;
            
            %% 计算模式
            calculation_mode_string=app.bearing_calculation_type.Value;
            switch calculation_mode_string
                case '载荷/弯矩-偏心率/倾斜角'
                    calculation_mode=1;
                case '偏心率/倾斜角-载荷/弯矩'
                    calculation_mode=0;
            end
            global_para.switch.calculation_mode=calculation_mode;
            
            %% 初始计算参数
            epsilion=app.epsilion;
            titling1=app.titling1;
            titling2=app.titling2;
            global_para.initial_para.epsilon=epsilion;
            global_para.initial_para.titling1=titling1*2*pi/360;    % 将角度转化为弧度
            global_para.initial_para.titling2=titling2*2*pi/360;    % 将角度转化为弧度
            
            
            %% 修形参数
            profile_mode=app.profile_mode;
            Rc=app.Rc;
            small_l=app.small_l;
            profile_other=app.profile_other;
            
            global_para.profile_para.profile_mode=profile_mode;
            global_para.profile_para.Rc=Rc;
            global_para.profile_para.small_l=small_l;
            global_para.profile_para.profile_other=profile_other;   % 手动输入的修形曲线
            
            
            %% 输入参数窗口显示
            input_cell{1,1}=char(strcat('轴承直径/mm：',string(Rbearing*2)));
            input_cell{2,1}=char(strcat('轴承宽度/mm：',string(Length)));
            input_cell{3,1}=char(strcat('宽径比：',string(Length/(Rbearing*2))));
            input_cell{4,1}=char(strcat('轴承间隙/mm：',string(Rbearing*relative_gap_ratio)));
            input_cell{5,1}=char(strcat('轴承间隙比：',string(relative_gap_ratio)));
            input_cell{6,1}=char(strcat('轴承转速/rpm：',string(RS)));
            input_cell{7,1}=char(strcat('轴承径向载荷/N：',string(Fz*2)));
            
            input_cell_2{1,1}=char(strcat('轴承弯矩/(N·m)：',string(Fx*2*bearing_pitch)));
            input_cell_2{2,1}=char(strcat('比压/MPa：',string(Fz*2/(Rbearing*2)/Length)));
            input_cell_2{3,1}=char(strcat('润滑介质黏度/(Pa·s)：',string(Vis)));
            input_cell_2{4,1}=char(strcat('润滑介质密度/(kg*m^3)：',string(Den)));
            input_cell_2{5,1}=char(strcat('计算模式：',calculation_mode_string));
            
            %% 修形类型
            if profile_mode==0
                profile_mode_string='不考虑';
            elseif profile_mode==1
                profile_mode_string='全圆弧修形';
            elseif profile_mode==2
                profile_mode_string='相切圆弧修形';
            elseif profile_mode==3
                profile_mode_string='相交圆弧修形';
            elseif profile_mode==4
                profile_mode_string='其他修形方式';
            end
            
            %% 温度计算类型
            heat_mode=app.heat_mode;
            global_para.switch.DENVIS_switch=heat_mode;
            global_para.temp_calculation.T_envir=app.T_envir;      % 环境温度--必定要有的参数(默认为40°)
            if heat_mode==0
                heat_mode_string='不考虑';
                
            elseif heat_mode==1
                heat_mode_string='绝热计算';
                %% 按照标准的流量进行计算，但是 摩擦力、端泄流量和摩擦系数均采用公式进行计算，而不是查表计算
                % 进油方式
                global_para.temp_calculation.heat_type=app.heat_type;   % 进油压力/进油量
                if app.heat_type==1
                    % 单孔进油/周向油槽
                    global_para.temp_calculation.hole_type=app.hole_type;
                    % 油孔直径
                    global_para.temp_calculation.hole_diameter=app.hole_diameter;
                    % 进油压力
                    global_para.temp_calculation.inlet_pressure=app.inlet_pressure;
                    if app.hole_type==1
                        % 单孔进油
                        global_para.temp_calculation.hG=[];
                    elseif app.hole_type==3
                        % 周向油槽
                        global_para.temp_calculation.hG=app.hG;
                    end
                    % 设置进油量为空集
                    global_para.temp_calculation.inlet_flow=[];
                elseif app.heat_type==2
                    % 单孔进油/周向油槽
                    global_para.temp_calculation.hole_type=[];
                    % 油孔直径
                    global_para.temp_calculation.hole_diameter=[];
                    % 进油压力
                    global_para.temp_calculation.inlet_pressure=[];
                    % 油腔深度
                    global_para.temp_calculation.hG=[];
                    % 进油量
                    global_para.temp_calculation.inlet_flow=app.inlet_flow;
                end
                global_para.temp_calculation.inlet_temp=app.inlet_temp;
            elseif heat_mode==2
                %% 按照标准计算时需要的参数较多
                heat_mode_string='按照标准计算';
                % 进油方式
                global_para.temp_calculation.heat_type=app.heat_type;   % 进油压力/进油量
                if app.heat_type==1
                    % 单孔进油/周向油槽
                    global_para.temp_calculation.hole_type=app.hole_type;
                    % 油孔直径
                    global_para.temp_calculation.hole_diameter=app.hole_diameter;
                    % 进油压力
                    global_para.temp_calculation.inlet_pressure=app.inlet_pressure;
                    if app.hole_type==1
                        % 单孔进油
                        global_para.temp_calculation.hG=[];
                    elseif app.hole_type==3
                        % 周向油槽
                        global_para.temp_calculation.hG=app.hG;
                    end
                    % 设置进油量为空集
                    global_para.temp_calculation.inlet_flow=[];
                elseif app.heat_type==2
                    % 单孔进油/周向油槽
                    global_para.temp_calculation.hole_type=[];
                    % 油孔直径
                    global_para.temp_calculation.hole_diameter=[];
                    % 进油压力
                    global_para.temp_calculation.inlet_pressure=[];
                    % 油腔深度
                    global_para.temp_calculation.hG=[];
                    % 进油量
                    global_para.temp_calculation.inlet_flow=app.inlet_flow;
                    
                end
                global_para.temp_calculation.inlet_temp=app.inlet_temp;
            end
            input_cell_2{6,1}=char(strcat('修形计算模式：',profile_mode_string));
            input_cell_2{7,1}=char(strcat('温度计算模式：',heat_mode_string));

            app.input_information.Value=input_cell;
            app.input_information_2.Value=input_cell_2;


            %%
            [U,L,global_para]=interation_solver(global_para);
            app.global_para=global_para;
            app.U=U;
            app.L=L;
            %%
%             d.Message = '计算完成';
%             d.Value=1;
%             pause(1)
%             close(d);
            %% 结果绘制
            % 结构参数
            Rbearing=global_para.structure_para.Rbearing;
            C0=global_para.structure_para.C0;
            
            % 各段轴承的长度及其位置
            bearing_num=global_para.split_para.bearing_num;
            Length_point=global_para.split_para.Length_point;
            AttiAng=global_para.middle_para.AttiAng;
            
            % 中间的参数信息
            Um=global_para.middle_para.Um;
            Pdim=global_para.middle_para.Pdim;
            for floor_num=1:bearing_num
                start_ps=2*floor_num-1;
                end_ps=2*floor_num;
                % 最终的计算结果信息
                non_unit_p=L{floor_num}{1,6};
                non_unit_h=L{floor_num}{1,7};
                unit_p=non_unit_p/Pdim;
%                 unit_p(end,:)=[];
                unit_h=non_unit_h*C0;
%                 unit_h(end,:)=[];
                
                % 网格信息
                [line_N,line_M]=size(non_unit_p);
                % [mesh_N,mesh_M]=meshgrid(line_N,line_M);
                % mesh_N=mesh_N';
                % mesh_M=mesh_M';
                %% 将轴承包角内的油膜压力映射到全部的轴承内
                
                
                % 借助偏位角对p和h的位置进行调整，以符合真实的位置
%                 DX=2*pi/(line_N-1);
%                 adjust_angle=3*pi/2-AttiAng;
%                 ele_num=floor(adjust_angle/DX);
                
%                 former_p=unit_p(ele_num+1:end,:);
%                 latter_p=unit_p(1:ele_num,:);
%                 unit_p=[former_p;latter_p];
%                 unit_p(line_N,:)=unit_p(1,:);
%                 
%                 former_h=unit_h(ele_num+1:end,:);
%                 latter_h=unit_h(1:ele_num,:);
%                 unit_h=[former_h;latter_h];
%                 unit_h(line_N,:)=unit_h(1,:);
                
                % 网格绘制
                x_label=linspace(-(U.xa+AttiAng),-(U.xb+AttiAng),line_N);
                y_label=linspace(Length_point(start_ps),Length_point(end_ps),line_M);
                
                x_mesh=zeros(line_M,line_N);
                y_mesh=zeros(line_M,line_N);
                z_mesh=zeros(line_M,line_N);
                for II=1:line_M
                    x_mesh(II,:)=Rbearing*cos(x_label);
                end
                for II=1:line_M
                    y_mesh(II,:)=Rbearing*sin(x_label);
                end
                for II=1:line_M
                    z_mesh(II,:)=y_label(II);
                end
                x_mesh=x_mesh';
                y_mesh=y_mesh';
                z_mesh=z_mesh';
                
                % 图形绘制
                unit_p=unit_p/1E6;
                single_max_p(floor_num)=max(max(unit_p));
                mesh(app.result_plot,z_mesh,y_mesh,x_mesh,unit_p,'facecolor','interp','facealpha',0.5);
                view(app.result_plot,120,40)
                colorbar(app.result_plot)
                title(app.result_plot,'');   % 不显示标题
                axis(app.result_plot,'off');
                axis(app.result_plot,'equal');
                app.result_plot.NextPlot='add';
            end
            app.result_plot.Title.String='油膜压力——三维视图/MPa';
            app.result_plot.NextPlot='replace';
            
            %% 结果显示
            [U,L,global_para]=bearing_property(U,L,global_para);
            Leakage=global_para.result.Leakage;
            fri_force=global_para.result.fri_force;
            fri_coef=global_para.result.fri_coef;
            % 工作轴承数、偏心率、偏位角、最小膜厚、最大膜压、摩擦力、摩擦功耗、流量
            % 
            epsilion=global_para.initial_para.epsilon;
            if heat_mode==0
                global_max_T=global_para.temp_calculation.T_envir;
            elseif heat_mode==1
                global_max_T=global_para.result.T;
            elseif heat_mode==2
                %% 按照标准计算温度
                global_max_T=global_para.result.T;
            elseif heat_mode==3
                %% 三维绝热温度场
                for floor_num=1:bearing_num
                    single_T=L{floor_num}{1,19}*(global_para.temp_calculation.T_envir+273.15)-273.15;
                    single_max_T(floor_num)=max(max(max(single_T)));
                end
                global_max_T=max(single_max_T);
            end
            % 最大工作黏度
            if global_para.switch.DENVIS_switch==0 || global_para.switch.DENVIS_switch==3
                %% 当不考虑温度 或者 温度为三维温度场计算时
                Vis=global_para.work_condition.Vis;     % 润滑介质的工作黏度
                for floor_num=1:bearing_num
                    max_non_unit_vis(floor_num)=max(max(max(L{floor_num}{1,17})));
                end
                max_unit_vis=max(max_non_unit_vis)*Vis;
            elseif global_para.switch.DENVIS_switch==2
                %% 当采用标准计算温度场时
                max_unit_vis=global_para.result.Vis;     % 润滑介质的工作黏度
            elseif global_para.switch.DENVIS_switch==1
                %% 当采用标准计算温度场时
                max_unit_vis=global_para.result.Vis;     % 润滑介质的工作黏度
            end
            %%
            output_cell=[];
%             output_cell{1,1}=char(strcat('起飞转速：',string(1)));
            output_cell{1,1}=char(strcat('偏心率：',string(epsilion)));
            output_cell{2,1}=char(strcat('偏位角：',string(AttiAng*360/(2*pi))));
            output_cell{3,1}=char(strcat('最小膜厚/mm：',string(min(min(unit_h)))));
            output_cell{4,1}=char(strcat('最大膜压/MPa：',string(max(max(single_max_p)))));
            output_cell{5,1}=char(strcat('最大工作黏度/（Pa*s）：',string(max(max(max_unit_vis)))));
            
%             if global_para.switch.DENVIS_switch==2
%                 output_cell_2{1,1}=char(strcat('起飞转速/rpm：',string(global_para.result.N_T)));
%             else
%                 output_cell_2{1,1}=char(strcat('起飞转速/rpm：',string(0)));
%             end
            %% patch（轴承数计算）(还要考虑轴向切分的情况)
            global_para.result.S0=2*Fz*global_para.structure_para.CR^2/(global_para.split_para.bearing_single_Length*1E-3*global_para.split_para.bearing_num*Rbearing*1E-3*2*max_unit_vis);

            output_cell_2{1,1}=char(strcat('轴承数：',string(global_para.result.S0)));
            output_cell_2{2,1}=char(strcat('摩擦力/N：',string(fri_force)));
            output_cell_2{3,1}=char(strcat('摩擦系数：',string(fri_coef)));
            output_cell_2{4,1}=char(strcat('端泄流量/(L/min)：',string(Leakage)));
            output_cell_2{5,1}=char(strcat('最高温度值/°：',string(global_max_T)));
            app.output_information.Value=output_cell;
            app.output_information_2.Value=output_cell_2;
        end

        % Value changed function: bearing_oil_type
        function bearing_oil_typeValueChanged(app, event)
            %% 当选择默认的
            value = app.bearing_oil_type.Value;
            switch value
                case 'ISO VG 320'
                    app.Vis=0.2768;
                    app.Den=860;
                case '其他'
                    oil_property_dialog(app,app.Vis,app.Den);
            end
        end

        % Value changed function: bearing_calculation_type
        function bearing_calculation_typeValueChanged(app, event)
            value = app.bearing_calculation_type.Value;
            switch value
                case '载荷/弯矩-偏心率/倾斜角'
                    app.epsilion=0.8;
                    app.titling1=0.001;
                    app.titling2=90;
                case '偏心率/倾斜角-载荷/弯矩'
                    initial_work_condition_para(app,app.epsilion,app.titling1,app.titling2);
            end
        end

        % Menu selected function: profile_full_circle_dialog
        function profile_full_circle_dialogSelected(app, event)
            %% 全圆弧修形母线
            profile_full_circle(app,app.Rc,app.profile_mode);
        end

        % Menu selected function: profile_tangency_circle_dialog
        function profile_tangency_circle_dialogSelected(app, event)
            %% 相切圆弧修形母线
            profile_tangency_circle(app,app.Rc,app.small_l,app.profile_mode);
        end

        % Menu selected function: profile_cross_circle_dialog
        function profile_cross_circle_dialogSelected(app, event)
            %% 相交圆弧修形母线
            profile_cross_circle(app,app.Rc,app.small_l,app.profile_mode);
        end

        % Menu selected function: profile_other_dialog
        function profile_other_dialogSelected(app, event)
            profile_other(app,app.profile_other);
        end

        % Menu selected function: heat_insulation
        function heat_insulationSelected(app, event)
            standard_heat_equation(app,app.heat_mode,app.heat_type, app.hole_type,...
                app.hole_diameter,...
                app.T_envir, app.inlet_temp, app.inlet_pressure)
        end

        % Menu selected function: standard_heat
        function standard_heatSelected(app, event)
            standard_heat(app,app.heat_mode,app.heat_type, app.hole_type,...
                app.hole_diameter,...
                app.T_envir, app.inlet_temp, app.inlet_pressure)
        end

        % Value changed function: plot_type
        function plot_typeValueChanged(app, event)
            value = app.plot_type.Value;
%             calculation_or_not=exist(app.global_para);
%             if calculation_or_not==0
%                 errordlg('请先进行计算');
%             end
            pic_type=app.plot_type.Value;
            global_para=app.global_para;
            U=app.U;
            L=app.L;
            if isempty(U)==1
                uialert(app.HDPB_calculationUIFigure,'请先进行计算',...
                    'errordlg');
                return;
            end
            switch pic_type
                case '油膜厚度——三维视图'
                    % 结构参数
                    Rbearing=global_para.structure_para.Rbearing;
                    C0=global_para.structure_para.C0;
                    
                    % 各段轴承的长度及其位置
                    bearing_num=global_para.split_para.bearing_num;
                    Length_point=global_para.split_para.Length_point;
                    AttiAng=global_para.middle_para.AttiAng;
                    
                    % 中间的参数信息
                    Um=global_para.middle_para.Um;
                    Pdim=global_para.middle_para.Pdim;
                    for floor_num=1:bearing_num
                        start_ps=2*floor_num-1;
                        end_ps=2*floor_num;
                        % 最终的计算结果信息
                        non_unit_p=L{floor_num}{1,6};
                        non_unit_h=L{floor_num}{1,7};
                        unit_p=non_unit_p/Pdim;
%                         unit_p(end,:)=[];
                        unit_h=non_unit_h/C0;
%                         unit_h(end,:)=[];
                        
                        % 网格信息
                        [line_N,line_M]=size(non_unit_p);
                        % [mesh_N,mesh_M]=meshgrid(line_N,line_M);
                        % mesh_N=mesh_N';
                        % mesh_M=mesh_M';
                        
                        % 借助偏位角对p和h的位置进行调整，以符合真实的位置
%                         DX=2*pi/(line_N-1);
%                         adjust_angle=3*pi/2-AttiAng;
%                         ele_num=floor(adjust_angle/DX);
                        
%                         former_p=unit_p(ele_num+1:end,:);
%                         latter_p=unit_p(1:ele_num,:);
%                         unit_p=[former_p;latter_p];
%                         unit_p(line_N,:)=unit_p(1,:);
%                         
%                         former_h=unit_h(ele_num+1:end,:);
%                         latter_h=unit_h(1:ele_num,:);
%                         unit_h=[former_h;latter_h];
%                         unit_h(line_N,:)=unit_h(1,:);
                        
                        % 网格绘制
                        x_label=linspace(-(U.xa+AttiAng),-(U.xb+AttiAng),line_N);
                        y_label=linspace(Length_point(start_ps),Length_point(end_ps),line_M);
                        
                        x_mesh=zeros(line_M,line_N);
                        y_mesh=zeros(line_M,line_N);
                        z_mesh=zeros(line_M,line_N);
                        for II=1:line_M
                            x_mesh(II,:)=Rbearing*cos(x_label);
                        end
                        for II=1:line_M
                            y_mesh(II,:)=Rbearing*sin(x_label);
                        end
                        for II=1:line_M
                            z_mesh(II,:)=y_label(II);
                        end
                        x_mesh=x_mesh';
                        y_mesh=y_mesh';
                        z_mesh=z_mesh';
                        
                        % 图形绘制
                        mesh(app.result_plot,z_mesh,y_mesh,x_mesh,unit_h,'facecolor','interp','facealpha',0.5);
                        view(app.result_plot,120,40)
                        colorbar(app.result_plot)
                        title(app.result_plot,'');   % 不显示标题
                        axis(app.result_plot,'off');
                        axis(app.result_plot,'equal');
                        app.result_plot.NextPlot='add';
                    end
                    app.result_plot.NextPlot='replacechildren';
                    app.result_plot.Title.String='油膜厚度——三维视图/mm';
                    app.result_plot.NextPlot='replace';
                case '油膜压力——三维视图'
                    % 结构参数
                    Rbearing=global_para.structure_para.Rbearing;
                    C0=global_para.structure_para.C0;
                    
                    % 各段轴承的长度及其位置
                    bearing_num=global_para.split_para.bearing_num;
                    Length_point=global_para.split_para.Length_point;
                    AttiAng=global_para.middle_para.AttiAng;
                    
                    % 中间的参数信息
                    Um=global_para.middle_para.Um;
                    Pdim=global_para.middle_para.Pdim;
                    for floor_num=1:bearing_num
                        start_ps=2*floor_num-1;
                        end_ps=2*floor_num;
                        % 最终的计算结果信息
                        non_unit_p=L{floor_num}{1,6};
                        non_unit_h=L{floor_num}{1,7};
                        unit_p=non_unit_p/Pdim;
%                         unit_p(end,:)=[];
                        unit_h=non_unit_h/C0;
%                         unit_h(end,:)=[];
                        
                        % 网格信息
                        [line_N,line_M]=size(non_unit_p);
                        % [mesh_N,mesh_M]=meshgrid(line_N,line_M);
                        % mesh_N=mesh_N';
                        % mesh_M=mesh_M';
                        
                        % 借助偏位角对p和h的位置进行调整，以符合真实的位置
%                         DX=2*pi/(line_N-1);
%                         adjust_angle=3*pi/2-AttiAng;
%                         ele_num=floor(adjust_angle/DX);
%                         
%                         former_p=unit_p(ele_num+1:end,:);
%                         latter_p=unit_p(1:ele_num,:);
%                         unit_p=[former_p;latter_p];
%                         unit_p(line_N,:)=unit_p(1,:);
%                         
%                         former_h=unit_h(ele_num+1:end,:);
%                         latter_h=unit_h(1:ele_num,:);
%                         unit_h=[former_h;latter_h];
%                         unit_h(line_N,:)=unit_h(1,:);
                        
                        % 网格绘制
                        x_label=linspace(-(U.xa+AttiAng),-(U.xb+AttiAng),line_N);
                        y_label=linspace(Length_point(start_ps),Length_point(end_ps),line_M);
                        
                        x_mesh=zeros(line_M,line_N);
                        y_mesh=zeros(line_M,line_N);
                        z_mesh=zeros(line_M,line_N);
                        for II=1:line_M
                            x_mesh(II,:)=Rbearing*cos(x_label);
                        end
                        for II=1:line_M
                            y_mesh(II,:)=Rbearing*sin(x_label);
                        end
                        for II=1:line_M
                            z_mesh(II,:)=y_label(II);
                        end
                        x_mesh=x_mesh';
                        y_mesh=y_mesh';
                        z_mesh=z_mesh';
                        
                        % 图形绘制
                        unit_p=unit_p/1E6;
                        mesh(app.result_plot,z_mesh,y_mesh,x_mesh,unit_p,'facecolor','interp','facealpha',0.5);
                        view(app.result_plot,120,40)
                        colorbar(app.result_plot)
                        title(app.result_plot,'');   % 不显示标题
                        axis(app.result_plot,'off');
                        axis(app.result_plot,'equal');
                        app.result_plot.NextPlot='add';
                    end
                    app.result_plot.NextPlot='replacechildren';
                    app.result_plot.Title.String='油膜压力——三维视图/MPa';
                    app.result_plot.NextPlot='replace';
                case '二维油膜厚度'
                    % 结构参数
                    Rbearing=global_para.structure_para.Rbearing;
                    C0=global_para.structure_para.C0;
                    
                    % 各段轴承的长度及其位置
                    bearing_num=global_para.split_para.bearing_num;
                    Length_point=global_para.split_para.Length_point;
                    AttiAng=global_para.middle_para.AttiAng;
                    
                    % 中间的参数信息
                    Um=global_para.middle_para.Um;
                    Pdim=global_para.middle_para.Pdim;
                    for floor_num=1:bearing_num
                        start_ps=2*floor_num-1;
                        end_ps=2*floor_num;
                        % 最终的计算结果信息
                        non_unit_p=L{floor_num}{1,6};
                        non_unit_h=L{floor_num}{1,7};
                        unit_p=non_unit_p/Pdim;
%                         unit_p(end,:)=[];
                        unit_h=non_unit_h/C0;
%                         unit_h(end,:)=[];
                        
                        % 网格信息
                        [line_N,line_M]=size(non_unit_p);
                        % [mesh_N,mesh_M]=meshgrid(line_N,line_M);
                        % mesh_N=mesh_N';
                        % mesh_M=mesh_M';
                        
                        % 借助偏位角对p和h的位置进行调整，以符合真实的位置
%                         DX=2*pi/(line_N-1);
%                         adjust_angle=3*pi/2-AttiAng;
%                         ele_num=floor(adjust_angle/DX);
%                         
%                         former_p=unit_p(ele_num+1:end,:);
%                         latter_p=unit_p(1:ele_num,:);
%                         unit_p=[former_p;latter_p];
%                         unit_p(line_N,:)=unit_p(1,:);
%                         
%                         former_h=unit_h(ele_num+1:end,:);
%                         latter_h=unit_h(1:ele_num,:);
%                         unit_h=[former_h;latter_h];
%                         unit_h(line_N,:)=unit_h(1,:);
                        
                        % 网格绘制
                        x_label=linspace(U.xa+AttiAng,U.xb+AttiAng,line_N);
                        y_label=linspace(Length_point(start_ps),Length_point(end_ps),line_M);
                        
                        [x_mesh,y_mesh]=meshgrid(x_label,y_label);
                        x_mesh=x_mesh';
                        y_mesh=y_mesh';
                        
                        % 图形绘制
                        mesh(app.result_plot,x_mesh,y_mesh,unit_h);
                        title(app.result_plot,'');   % 不显示标题
                        app.result_plot.NextPlot='add';
                    end
                    app.result_plot.NextPlot='replacechildren';
                    app.result_plot.Title.String='二维油膜厚度分布/mm';
                    xlabel(app.result_plot,'圆周方向位置/°');
                    ylabel(app.result_plot,'轴向方向位置/mm°');
                    app.result_plot.NextPlot='replace';
                case '二维油膜压力'
                    % 结构参数
                    Rbearing=global_para.structure_para.Rbearing;
                    C0=global_para.structure_para.C0;
                    
                    % 各段轴承的长度及其位置
                    bearing_num=global_para.split_para.bearing_num;
                    Length_point=global_para.split_para.Length_point;
                    AttiAng=global_para.middle_para.AttiAng;
                    
                    % 中间的参数信息
                    Um=global_para.middle_para.Um;
                    Pdim=global_para.middle_para.Pdim;
                    for floor_num=1:bearing_num
                        start_ps=2*floor_num-1;
                        end_ps=2*floor_num;
                        % 最终的计算结果信息
                        non_unit_p=L{floor_num}{1,6};
                        non_unit_h=L{floor_num}{1,7};
                        unit_p=non_unit_p/Pdim;
%                         unit_p(end,:)=[];
                        unit_h=non_unit_h/C0;
%                         unit_h(end,:)=[];
                        
                        % 网格信息
                        [line_N,line_M]=size(non_unit_p);
                        % [mesh_N,mesh_M]=meshgrid(line_N,line_M);
                        % mesh_N=mesh_N';
                        % mesh_M=mesh_M';
                        
                        % 借助偏位角对p和h的位置进行调整，以符合真实的位置
%                         DX=2*pi/(line_N-1);
%                         adjust_angle=3*pi/2-AttiAng;
%                         ele_num=floor(adjust_angle/DX);
                        
%                         former_p=unit_p(ele_num+1:end,:);
%                         latter_p=unit_p(1:ele_num,:);
%                         unit_p=[former_p;latter_p];
%                         unit_p(line_N,:)=unit_p(1,:);
%                         
%                         former_h=unit_h(ele_num+1:end,:);
%                         latter_h=unit_h(1:ele_num,:);
%                         unit_h=[former_h;latter_h];
%                         unit_h(line_N,:)=unit_h(1,:);
                        
                        % 网格绘制
                        x_label=linspace(U.xa+AttiAng,U.xb+AttiAng,line_N);
                        y_label=linspace(Length_point(start_ps),Length_point(end_ps),line_M);
                        
                        [x_mesh,y_mesh]=meshgrid(x_label,y_label);
                        x_mesh=x_mesh';
                        y_mesh=y_mesh';
                        
                        % 图形绘制
                        unit_p=unit_p/1E6;
                        mesh(app.result_plot,x_mesh,y_mesh,unit_p);
                        title(app.result_plot,'');   % 不显示标题
                        app.result_plot.NextPlot='add';
                    end
                    app.result_plot.NextPlot='replacechildren';
                    app.result_plot.Title.String='二维油膜压力/MPa';
                    xlabel(app.result_plot,'圆周方向位置/°');
                    ylabel(app.result_plot,'轴向方向位置/mm°');
                    app.result_plot.NextPlot='replace';
            end
        end

        % Callback function
        function MenuSelected(app, event)
            heat_insulation_3D(app,app.conduction,app.specific_heat,app.flow_value,app.heat_mode,app.T_envir,app.T_inlet);
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            app.heat_mode=0;
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            app.profile_mode=0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HDPB_calculationUIFigure = uifigure;
            app.HDPB_calculationUIFigure.Name = 'HDPB_calculation';
            app.profile = uimenu(app.HDPB_calculationUIFigure);
            app.profile.Text = '    修形计算    ';
            app.Menu_3 = uimenu(app.profile);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '不考虑';
            app.profile_full_circle_dialog = uimenu(app.profile);
            app.profile_full_circle_dialog.MenuSelectedFcn = createCallbackFcn(app, @profile_full_circle_dialogSelected, true);
            app.profile_full_circle_dialog.Text = '全圆弧母线';
            app.profile_tangency_circle_dialog = uimenu(app.profile);
            app.profile_tangency_circle_dialog.MenuSelectedFcn = createCallbackFcn(app, @profile_tangency_circle_dialogSelected, true);
            app.profile_tangency_circle_dialog.Text = '相切圆弧修正母线';
            app.profile_cross_circle_dialog = uimenu(app.profile);
            app.profile_cross_circle_dialog.MenuSelectedFcn = createCallbackFcn(app, @profile_cross_circle_dialogSelected, true);
            app.profile_cross_circle_dialog.Text = '相交圆弧修正母线';
            app.profile_other_dialog = uimenu(app.profile);
            app.profile_other_dialog.MenuSelectedFcn = createCallbackFcn(app, @profile_other_dialogSelected, true);
            app.profile_other_dialog.Text = '其他';
            app.heat = uimenu(app.HDPB_calculationUIFigure);
            app.heat.Text = '    温度计算    ';
            app.Menu_2 = uimenu(app.heat);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '不考虑';
            app.heat_insulation = uimenu(app.heat);
            app.heat_insulation.MenuSelectedFcn = createCallbackFcn(app, @heat_insulationSelected, true);
            app.heat_insulation.Text = '绝热计算';
            app.standard_heat = uimenu(app.heat);
            app.standard_heat.MenuSelectedFcn = createCallbackFcn(app, @standard_heatSelected, true);
            app.standard_heat.Text = '按照标准计算';
            app.TabGroup = uitabgroup(app.HDPB_calculationUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '模型';
            app.pic_2 = uibutton(app.Tab, 'push');
            app.pic_2.Icon = 'planet_load_1.png';
            app.pic_2.Text = '';
            app.pic_1 = uibutton(app.Tab, 'push');
            app.pic_1.Icon = 'hdpb_pic.jpg';
            app.pic_1.Text = '';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '计算结果';
            app.result_plot = uiaxes(app.Tab_2);
            app.input_information = uitextarea(app.Tab_2);
            app.input_information.Editable = 'off';
            app.input_information.Value = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.input_information_2 = uitextarea(app.Tab_2);
            app.input_information_2.Editable = 'off';
            app.input_information_2.Value = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.output_information = uitextarea(app.Tab_2);
            app.output_information.Editable = 'off';
            app.output_information.Value = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.output_information_2 = uitextarea(app.Tab_2);
            app.output_information_2.Editable = 'off';
            app.output_information_2.Value = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.structure = uipanel(app.HDPB_calculationUIFigure);
            app.structure.Title = '结构参数';
            app.mmEditFieldLabel = uilabel(app.structure);
            app.mmEditFieldLabel.Text = '轴承直径/mm';
            app.bearing_diameter = uieditfield(app.structure, 'numeric');
            app.bearing_diameter.Value = 320;
            app.mmLabel = uilabel(app.structure);
            app.mmLabel.Text = '轴承长度/mm';
            app.bearing_Length = uieditfield(app.structure, 'numeric');
            app.bearing_Length.Value = 390;
            app.Label = uilabel(app.structure);
            app.Label.Text = '相对间隙比';
            app.bearing_gap = uieditfield(app.structure, 'numeric');
            app.bearing_gap.Value = 0.001;
            app.Label_2 = uilabel(app.structure);
            app.Label_2.Text = '轴向段数';
            app.bearing_bearing_num = uieditfield(app.structure, 'numeric');
            app.bearing_bearing_num.Value = 1;
            app.Label_3 = uilabel(app.structure);
            app.Label_3.Text = '每段长度';
            app.bearing_Length_single = uieditfield(app.structure, 'numeric');
            app.bearing_Length_single.Value = 390;
            app.work_condtion = uipanel(app.HDPB_calculationUIFigure);
            app.work_condtion.Title = '工况参数';
            app.FxNLabel = uilabel(app.work_condtion);
            app.FxNLabel.Text = '啮合力Fx/N';
            app.bearing_Fx = uieditfield(app.work_condtion, 'numeric');
            app.bearing_Fx.ValueDisplayFormat = '%.0f';
            app.FyNLabel = uilabel(app.work_condtion);
            app.FyNLabel.Text = '啮合力Fy/N';
            app.bearing_Fy = uieditfield(app.work_condtion, 'numeric');
            app.bearing_Fy.ValueDisplayFormat = '%.0f';
            app.FzNLabel = uilabel(app.work_condtion);
            app.FzNLabel.Text = '啮合力Fz/N';
            app.bearing_Fz = uieditfield(app.work_condtion, 'numeric');
            app.bearing_Fz.ValueDisplayFormat = '%.0f';
            app.bearing_Fz.Value = 704125;
            app.rpmLabel = uilabel(app.work_condtion);
            app.rpmLabel.Text = '轴承转速/rpm';
            app.bearing_speed = uieditfield(app.work_condtion, 'numeric');
            app.bearing_speed.Value = 29;
            app.Label_4 = uilabel(app.work_condtion);
            app.Label_4.Text = '润滑油';
            app.bearing_oil_type = uidropdown(app.work_condtion);
            app.bearing_oil_type.Items = {'ISO VG 320', '其他', 'ISO VG 100'};
            app.bearing_oil_type.ValueChangedFcn = createCallbackFcn(app, @bearing_oil_typeValueChanged, true);
            app.bearing_oil_type.Value = 'ISO VG 320';
            app.mmLabel_2 = uilabel(app.work_condtion);
            app.mmLabel_2.Text = '齿轮节圆半径/mm';
            app.bearing_pitch_radius = uieditfield(app.work_condtion, 'numeric');
            app.bearing_pitch_radius.ValueDisplayFormat = '%.3f';
            app.bearing_pitch_radius.Value = 247.12;
            app.Label_6 = uilabel(app.work_condtion);
            app.Label_6.Text = '计算模式';
            app.bearing_calculation_type = uidropdown(app.work_condtion);
            app.bearing_calculation_type.Items = {'载荷/弯矩-偏心率/倾斜角', '偏心率/倾斜角-载荷/弯矩'};
            app.bearing_calculation_type.ValueChangedFcn = createCallbackFcn(app, @bearing_calculation_typeValueChanged, true);
            app.bearing_calculation_type.Value = '载荷/弯矩-偏心率/倾斜角';
            app.Button = uibutton(app.HDPB_calculationUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.Panel = uipanel(app.HDPB_calculationUIFigure);
            app.Panel.Title = '结果绘制';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '图形绘制';
            app.plot_type = uidropdown(app.Panel);
            app.plot_type.Items = {'请选择', '油膜厚度——三维视图', '油膜压力——三维视图', '二维油膜厚度', '二维油膜压力'};
            app.plot_type.ValueChangedFcn = createCallbackFcn(app, @plot_typeValueChanged, true);
            app.plot_type.Value = '请选择';
            app.Label_8 = uilabel(app.HDPB_calculationUIFigure);
            app.Label_8.Text = '轴承包角/°';
            app.bearing_angle_area = uieditfield(app.HDPB_calculationUIFigure, 'numeric');
            app.bearing_angle_area.Value = 360;
        end
    end

    methods (Access = public)

        % Construct app
        function app = HDPB_first

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HDPB_calculationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HDPB_calculationUIFigure)
        end
    end
end