classdef heat_insulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        JKgKLabel      matlab.ui.control.Label
        specific_heat  matlab.ui.control.NumericEditField
        Label_2        matlab.ui.control.Label
        T_envir        matlab.ui.control.NumericEditField
        Button         matlab.ui.control.Button
        Label_3        matlab.ui.control.Label
        heat_mode      matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp; % main app object
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, specific_heat, T_envir, heat_mode)
            app.CallingApp = mainapp;
            
            app.specific_heat.Value=specific_heat;
            app.T_envir.Value=T_envir;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            update_oil_heat_property(app.CallingApp,app.specific_heat.Value,app.T_envir.Value,app.heat_mode.Value)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.JKgKLabel = uilabel(app.UIFigure);
            app.JKgKLabel.Text = '润滑介质比热容/(J/(Kg*K))';
            app.specific_heat = uieditfield(app.UIFigure, 'numeric');
            app.specific_heat.Value = 2000;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '工作环境温度/°';
            app.T_envir = uieditfield(app.UIFigure, 'numeric');
            app.T_envir.Value = 40;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '温度计算类型';
            app.heat_mode = uieditfield(app.UIFigure, 'numeric');
            app.heat_mode.Enable = 'off';
            app.heat_mode.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = heat_insulation(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end