classdef heat_insulation_3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        heat_conduction     matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        specific_heat       matlab.ui.control.NumericEditField
        Button              matlab.ui.control.Button
        Label_2             matlab.ui.control.Label
        heat_mode           matlab.ui.control.NumericEditField
        Label_3             matlab.ui.control.Label
        T_envir             matlab.ui.control.NumericEditField
        LminEditFieldLabel  matlab.ui.control.Label
        flow_value          matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        oil_inlet_temp      matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp; % main app object
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, conduction, specific_heat, flow, heat_mode, T_envir, T_inlet)
            app.CallingApp = mainapp;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            update_oil_heat_3D_property(app.CallingApp,app.heat_conduction.Value,app.specific_heat.Value,app.flow_value.Value,app.heat_mode.Value,app.T_envir.Value,app.oil_inlet_temp.Value)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '润滑介质热传导系数';
            app.heat_conduction = uieditfield(app.UIFigure, 'numeric');
            app.heat_conduction.Value = 0.13;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '润滑介质比热容';
            app.specific_heat = uieditfield(app.UIFigure, 'numeric');
            app.specific_heat.Value = 2000;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '温度计算模式';
            app.heat_mode = uieditfield(app.UIFigure, 'numeric');
            app.heat_mode.Enable = 'off';
            app.heat_mode.Value = 3;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '环境温度/°';
            app.T_envir = uieditfield(app.UIFigure, 'numeric');
            app.T_envir.Value = 40;
            app.LminEditFieldLabel = uilabel(app.UIFigure);
            app.LminEditFieldLabel.Text = '注油量/(L/min)';
            app.flow_value = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '进油温度/°';
            app.oil_inlet_temp = uieditfield(app.UIFigure, 'numeric');
            app.oil_inlet_temp.Value = 40;
        end
    end

    methods (Access = public)

        % Construct app
        function app = heat_insulation_3D(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end