classdef initial_work_condition_para < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_2                     matlab.ui.control.Label
        initial_work_para_titling1  matlab.ui.control.NumericEditField
        EditField_3Label            matlab.ui.control.Label
        initial_work_para_titling2  matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        initial_work_para_epsilion  matlab.ui.control.NumericEditField
        Button                      matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp % main app object
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, epsilion, titling1, titling2)
            app.CallingApp = mainapp;
            
            app.initial_work_para_titling1.Value=titling1;
            app.initial_work_para_titling2.Value=titling2;
            app.initial_work_para_epsilion.Value=epsilion;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            update_initial_work_para(app.CallingApp,app.initial_work_para_epsilion.Value,app.initial_work_para_titling1.Value, app.initial_work_para_titling2.Value)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '轴承倾斜角α/°';
            app.initial_work_para_titling1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '轴承倾斜角β/°';
            app.initial_work_para_titling2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '轴承偏心率';
            app.initial_work_para_epsilion = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
        end
    end

    methods (Access = public)

        % Construct app
        function app = initial_work_condition_para(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end