classdef profile_other < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UITable       matlab.ui.control.Table
        add_order     matlab.ui.control.Button
        delete_order  matlab.ui.control.Button
        ok            matlab.ui.control.Button
        inlet_order   matlab.ui.control.Button
    end


    properties (Access = private)
        t; % Description
        mouse_ind;
        mouse_ind2;
        newData;
        
        %% 链接
        CallingApp; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, profile_other)
            %% 表格大小初始化
            app.t =  app.UITable.Data;
            nr = {[],[]};
            app.UITable.Data=[app.t;nr];   %能反复新增，即增加多行
            app.t =  app.UITable.Data;
            newData1 = app.t;
            set(app.UITable,'Data',newData1);
            
            %% 链接
            app.CallingApp = mainapp;
            
        end

        % Button pushed function: add_order
        function add_orderPushed(app, event)
            app.t =  app.UITable.Data;
            nr = {[],[]};
            app.UITable.Data=[app.t;nr];   %能反复新增，即增加多行
            app.t =  app.UITable.Data;
            newData1 = app.t;
            set(app.UITable,'Data',newData1);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.newData = event.NewData;
            app.UITable.Data(app.mouse_ind,app.mouse_ind2)=cellstr(num2str(app.newData)); 
            app.t=app.UITable.Data;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.mouse_ind = indices(1);  %鼠标选择行
            app.mouse_ind2 = indices(2);  %鼠标选择列
        end

        % Button pushed function: inlet_order
        function inlet_orderPushed(app, event)
            [excelfile, excelpath] = uigetfile({'*.xlsx';'*.xls';'*.mat';'*.*'},'导入数据');
            excelfull = strcat(excelpath,excelfile);
            new2 = xlsread(excelfull);
            [m, n] = size(new2);
            for i = 1:1:m
                for j=1:1:n
                    app.UITable.Data{i,j}= num2str(new2(i,j));
                end
            end
        app.t = app.UITable.Data;
        end

        % Button pushed function: delete_order
        function delete_orderButtonPushed(app, event)
            app.UITable.Data(app.mouse_ind,:) =[];
            app.t=app.UITable.Data;
        end

        % Button pushed function: ok
        function okButtonPushed(app, event)
            % profile_other=app.UITable.Data;
            update_profile_other(app.CallingApp,app.UITable.Data)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'位置/mm(0-L)'; '修形高度/mm'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.add_order = uibutton(app.UIFigure, 'push');
            app.add_order.ButtonPushedFcn = createCallbackFcn(app, @add_orderPushed, true);
            app.add_order.Text = '增加';
            app.delete_order = uibutton(app.UIFigure, 'push');
            app.delete_order.ButtonPushedFcn = createCallbackFcn(app, @delete_orderButtonPushed, true);
            app.delete_order.Text = '删除';
            app.ok = uibutton(app.UIFigure, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.ok.Text = '确定';
            app.inlet_order = uibutton(app.UIFigure, 'push');
            app.inlet_order.ButtonPushedFcn = createCallbackFcn(app, @inlet_orderPushed, true);
            app.inlet_order.Text = '导入';
        end
    end

    methods (Access = public)

        % Construct app
        function app = profile_other(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end