classdef profile_tangency_circle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RcmmLabel          matlab.ui.control.Label
        Rc_EditField       matlab.ui.control.NumericEditField
        LmmLabel           matlab.ui.control.Label
        small_l_EditField  matlab.ui.control.NumericEditField
        Button             matlab.ui.control.Button
        Label              matlab.ui.control.Label
        profile_mode       matlab.ui.control.NumericEditField
        Button_2           matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp % main app object
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Rc, small_l, profile_mode)

            
            app.CallingApp = mainapp;
            
            app.Rc_EditField.Value=Rc;
            
            app.small_l_EditField.Value=small_l;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            update_profile_tangency_circle(app.CallingApp,app.Rc_EditField.Value,app.small_l_EditField.Value,app.profile_mode.Value)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RcmmLabel = uilabel(app.UIFigure);
            app.RcmmLabel.Text = 'Rc/mm';
            app.Rc_EditField = uieditfield(app.UIFigure, 'numeric');
            app.LmmLabel = uilabel(app.UIFigure);
            app.LmmLabel.Text = 'L/mm';
            app.small_l_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '修形模式';
            app.profile_mode = uieditfield(app.UIFigure, 'numeric');
            app.profile_mode.Enable = 'off';
            app.profile_mode.Value = 2;
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = 'profile_tangency_circle.jpg';
            app.Button_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = profile_tangency_circle(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end