classdef standard_heat_equation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label_2             matlab.ui.control.Label
        hole_type           matlab.ui.control.DropDown
        dLbGmmLabel         matlab.ui.control.Label
        hole_diameter       matlab.ui.control.NumericEditField
        Label_7             matlab.ui.control.Label
        T_envir             matlab.ui.control.NumericEditField
        EditField_5Label    matlab.ui.control.Label
        inlet_temp          matlab.ui.control.NumericEditField
        MPaEditFieldLabel   matlab.ui.control.Label
        inlet_pressure      matlab.ui.control.NumericEditField
        Button              matlab.ui.control.Button
        Label_10            matlab.ui.control.Label
        heat_mode           matlab.ui.control.NumericEditField
        LminEditFieldLabel  matlab.ui.control.Label
        flow                matlab.ui.control.NumericEditField
        pic                 matlab.ui.control.Button
        Label_11            matlab.ui.control.Label
        heat_type           matlab.ui.control.DropDown
        hGmmLabel           matlab.ui.control.Label
        oil_groove_depth    matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, heat_mode, heat_type, hole_type, hole_diameter, hG, T_envir, inlet_temp, inlet_pressure, flow)
            app.CallingApp = mainapp;
            set(app.flow,'enable','off');    % 关闭进油量
            set(app.oil_groove_depth,'enable','off');    % 关闭 油腔深度（用于周向油槽）
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            update_standard_heat(app.CallingApp,app.heat_mode.Value,app.heat_type.Value,app.hole_type.Value,app.hole_diameter.Value,app.oil_groove_depth.Value,app.T_envir.Value,app.inlet_temp.Value,app.inlet_pressure.Value,app.flow.Value);
            delete(app)
        end

        % Value changed function: hole_type
        function hole_typeValueChanged(app, event)
            value = app.hole_type.Value;
            switch value
                case '单孔进油'
                    %% 现在只考虑载荷反方向单孔进油
                    HolePosition = 1;
                    
                    %% 图片替换
                    app.pic.Icon = 'single_hole_pic.png';
                    app.pic.BackgroundColor = [1 1 1];
                    app.pic.Position = [1 225 280 175];
                    app.pic.Text = '';
                    %% 参数是否能够输入的设置
                    set(app.oil_groove_depth,'enable','off');    % 关闭 油腔深度（用于周向油槽）
                   
                case '周向油槽'
                    %% 轴承被分为 N 份
                    HolePosition = 3;
                    
                    %% 图片替换
                    app.pic.Icon = 'circle_oil_groove.png';
                    app.pic.BackgroundColor = [1 1 1];
                    app.pic.Position = [1 225 280 175];
                    app.pic.Text = '';
                    %% 参数是否能够输入的设置
                    set(app.oil_groove_depth,'enable','on');    % 关闭 油腔深度（用于周向油槽）
            end
            
        end

        % Value changed function: heat_type
        function heat_typeValueChanged(app, event)
            value = app.heat_type.Value;
            switch value
                case '输入进油压力'
                    set(app.flow,'enable','off');
                    set(app.hole_type,'enable','on');   % 打开注油类型
                    set(app.hole_diameter,'enable','on');   % 打开孔径
                    set(app.inlet_pressure,'enable','on');   % 打开进油压力
                    
                case '输入进油量'
                    set(app.flow,'enable','on');    % 打开进油量
                    set(app.hole_type,'enable','off');   % 关闭注油类型
                    set(app.hole_diameter,'enable','off');   % 关闭孔径
                    set(app.inlet_pressure,'enable','off');   % 关闭进油压力
                    
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '进油类型';
            app.hole_type = uidropdown(app.UIFigure);
            app.hole_type.Items = {'单孔进油', '周向油槽'};
            app.hole_type.ValueChangedFcn = createCallbackFcn(app, @hole_typeValueChanged, true);
            app.hole_type.Value = '单孔进油';
            app.dLbGmmLabel = uilabel(app.UIFigure);
            app.dLbGmmLabel.Text = '进油孔直径dL/bG/mm';
            app.hole_diameter = uieditfield(app.UIFigure, 'numeric');
            app.hole_diameter.Value = 17;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '工作环境温度/°';
            app.T_envir = uieditfield(app.UIFigure, 'numeric');
            app.T_envir.Value = 20;
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '进油温度/°';
            app.inlet_temp = uieditfield(app.UIFigure, 'numeric');
            app.inlet_temp.Value = 40;
            app.MPaEditFieldLabel = uilabel(app.UIFigure);
            app.MPaEditFieldLabel.Text = '进油压力/MPa';
            app.inlet_pressure = uieditfield(app.UIFigure, 'numeric');
            app.inlet_pressure.Value = 0.1;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确定';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '温度计算类型';
            app.heat_mode = uieditfield(app.UIFigure, 'numeric');
            app.heat_mode.Enable = 'off';
            app.heat_mode.Value = 1;
            app.LminEditFieldLabel = uilabel(app.UIFigure);
            app.LminEditFieldLabel.Text = '进油量/(L/min)';
            app.flow = uieditfield(app.UIFigure, 'numeric');
            app.pic = uibutton(app.UIFigure, 'push');
            app.pic.Icon = 'single_hole_pic.png';
            app.pic.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '计算类型';
            app.heat_type = uidropdown(app.UIFigure);
            app.heat_type.Items = {'输入进油压力', '输入进油量'};
            app.heat_type.ValueChangedFcn = createCallbackFcn(app, @heat_typeValueChanged, true);
            app.heat_type.Value = '输入进油压力';
            app.hGmmLabel = uilabel(app.UIFigure);
            app.hGmmLabel.Text = '油腔深度hG/mm';
            app.oil_groove_depth = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = standard_heat_equation(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end